classdef CubeRotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        PrincipalAngleSpinner       matlab.ui.control.Spinner
        PrincipalAngleSpinnerLabel  matlab.ui.control.Label
        v3Spinner                   matlab.ui.control.Spinner
        v3SpinnerLabel              matlab.ui.control.Label
        v2Spinner                   matlab.ui.control.Spinner
        v2SpinnerLabel              matlab.ui.control.Label
        v1Spinner                   matlab.ui.control.Spinner
        v1SpinnerLabel              matlab.ui.control.Label
        SelectAxisDropDown          matlab.ui.control.DropDown
        SelectAxisDropDownLabel     matlab.ui.control.Label
        zSpinner                    matlab.ui.control.Spinner
        zSpinnerLabel               matlab.ui.control.Label
        ySpinner                    matlab.ui.control.Spinner
        ySpinnerLabel               matlab.ui.control.Label
        xSpinner                    matlab.ui.control.Spinner
        xSpinnerLabel               matlab.ui.control.Label
        wSpinner                    matlab.ui.control.Spinner
        wSpinnerLabel               matlab.ui.control.Label
        YawSpinner                  matlab.ui.control.Spinner
        YawSpinnerLabel             matlab.ui.control.Label
        PitchSpinner                matlab.ui.control.Spinner
        PitchSpinnerLabel           matlab.ui.control.Label
        RollSpinner                 matlab.ui.control.Spinner
        RollSpinnerLabel            matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        RotationMatrixLabel         matlab.ui.control.Label
        PrincipalAxisButtonGroup    matlab.ui.container.ButtonGroup
        ZButton                     matlab.ui.control.RadioButton
        YButton                     matlab.ui.control.RadioButton
        XButton                     matlab.ui.control.RadioButton
        ResetButton                 matlab.ui.control.Button
        RotationVectorLabel         matlab.ui.control.Label
        EulerAnglesLabel            matlab.ui.control.Label
        EulerprincipalAngleandAxisLabel  matlab.ui.control.Label
        QuaternionLabel             matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Constant)
        A = [0 0 0];
        B = [1 0 0];
        C = [0 1 0];
        D = [0 0 1];
        E = [0 1 1];
        F = [1 0 1];
        G = [1 1 0];
        H = [1 1 1];


    end

    properties (Access = private)

        Cube = [CubeRotation.A;CubeRotation.B;CubeRotation.F;CubeRotation.H;CubeRotation.G;CubeRotation.C;CubeRotation.A;CubeRotation.D;CubeRotation.E;CubeRotation.H;CubeRotation.F;CubeRotation.D;CubeRotation.E;CubeRotation.C;CubeRotation.G;CubeRotation.B]; % cube created

        VertexMatrix = [0 0 0
                        1 0 0
                        1 1 0
                        0 1 0
                        0 0 1
                        1 0 1
                        1 1 1
                        0 1 1]-0.5;
        FacesMatrix = [1 2 6 5
                       2 3 7 6
                       3 4 8 7
                       4 1 5 8
                       1 2 3 4
                       5 6 7 8];

        Quaternion = [0;0;0;0] % w, x, y, z

        PrincipalAxis = [0;0;0]; % x, y, z
        PrinciaplAngle = 0;

        Roll = 0;
        Pitch =0;
        Yaw = 0;

        RotationVector = [0;0;0]; % x,y,z

        RotaionMatrix = [0 0 0; 0 0 0; 0 0 0];

       currentPatch;

    
    end
    
    methods (Access = private)
        
       function mult = QuaternionMultiplication(app,q,p)
        %Hamilton formula
        mult(1) = q(1)*p(1) - q(2)*p(2) - q(3)*p(3) - q(4)*p(4);
        mult(2) = q(1)*p(2) + q(2)*p(1) + q(3)*p(4) - q(4)*p(3);
        mult(3) = q(1)*p(3) - q(2)*p(4) + q(3)*p(1) + q(4)*p(2);
        mult(4) = q(1)*p(4) + q(2)*p(3) - q(3)*p(2) + q(4)*p(1);
       end
       function qc = Conjugate(app,q)
         qc(1) = q(1);
         qc(2) = q(2)*-1;
         qc(3) = q(3)*-1;
         qc(4) = q(4)*-1;
       end
       function rotatedVertices = RotationMatrixQuaternion(app,q) % roatation matrix by quaternion
     
        initialVertex = app.VertexMatrix;

        rm =[  q(1)^2+q(2)^2-q(3)^2-q(4)^2   2*(q(2)*q(3)-q(1)*q(4)),          2*(q(4)*q(2)+q(1)*q(3)) ;
               2*(q(2)*q(3)+q(1)*q(4))       q(1)^2+q(3)^2-q(2)^2-q(4)^2       2*(q(3)*q(4)-q(1)*q(2)) ;
               2*(q(4)*q(2)-q(1)*q(3))       2*(q(3)*q(4)+q(1)*q(2))           q(1)^2+q(4)^2-q(2)^2-q(3)^2];       
         
       rotatedVertices = initialVertex * rm;
       end

       function rotatedVertices = RotationMatrixEulerAngles(app,roll,pitch,yaw) % Rotation matrix by euler angles
            
          initialVertex = app.VertexMatrix;
       
          Rx = [1 0         0;
               0  cosd(roll) -sind(roll); 
               0  sind(roll) cosd(roll)];

          Ry = [cosd(pitch)  0    sind(pitch); 
                0           1    0;     
                -sind(pitch) 0    cosd(pitch)];

          Rz = [cosd(yaw) -sind(yaw) 0; 
                sind(yaw) cosd(yaw)  0; 
                0        0         1];
    
          Rxyz = Rx*Ry*Rz;
            
          rotatedVertices = initialVertex * Rxyz;

       end

       function rotatedVertices = RotationMatrixPrincipalAxis(app, axis, angle)
             
          initialVertex = app.VertexMatrix;
           
          %rodriguez formula
          M = [axis(1)^2+(1-axis(1)^2)*cosd(angle)                  axis(1)*axis(2)*(1-cosd(angle))-axis(3)*sind(angle)    axis(1)*axis(3)*(1-cosd(angle))+axis(2)*sind(angle);
               axis(1)*axis(2)*(1-cosd(angle))+axis(3)*sind(angle)  axis(2)^2+(1-axis(2)^2)*cosd(angle)                    axis(2)*axis(3)*(1-cosd(angle))-axis(1)*sind(angle);
               axis(1)*axis(3)*(1-cosd(angle))-axis(2)*sind(angle)  axis(2)*axis(3)*(1-cosd(angle))+axis(1)*sind(angle)    axis(3)^2+(1-axis(3)^2)*cosd(angle)];
         
          Rxyz = M;

          rotatedVertices = initialVertex * Rxyz;
       end
        
        function DrawCube(app,vertexMatrix)
            

            delete(app.currentPatch);
            view(app.UIAxes,3);
            app.currentPatch =  patch(app.UIAxes,'Faces',app.FacesMatrix,'Vertices',vertexMatrix,'FaceVertexCData',(1:6)','FaceColor','flat');
            alpha(app.UIAxes, 0.7);  
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           DrawCube(app,app.VertexMatrix);
        end

        % Callback function
        function RotateButtonPushed(app, event)
 %delete
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                       
            app.RollSpinner.Value = 0;
            app.PitchSpinner.Value = 0;
            app.YawSpinner.Value = 0;

            app.wSpinner.Value = 0;
            app.xSpinner.Value = 0;
            app.ySpinner.Value = 0;
            app.zSpinner.Value = 0;

            app.v1Spinner.Value = 0;
            app.v2Spinner.Value = 0;
            app.v3Spinner.Value = 0;
         
            DrawCube(app,app.VertexMatrix);
        end

        % Value changed function: PitchSpinner, RollSpinner, YawSpinner
        function RollSpinnerValueChanged(app, event)
            roll = app.RollSpinner.Value;
            pitch = app.PitchSpinner.Value;
            yaw = app.YawSpinner.Value;

            rvm = RotationMatrixEulerAngles(app,roll,pitch,yaw);
           
            DrawCube(app,rvm);
        end

        % Value changed function: wSpinner, xSpinner, ySpinner
        function wSpinnerValueChanged(app, event)
            w = app.wSpinner.Value;
            x = app.xSpinner.Value;
            y = app.ySpinner.Value;
            z = app.zSpinner.Value;
            
            q = [w,x,y,z];

            rvm = RotationMatrixQuaternion(app,q);

            DrawCube(app,rvm);
            
        end

        % Value changed function: SelectAxisDropDown, v1Spinner, 
        % v2Spinner, v3Spinner
        function SelectAxisDropDownValueChanged(app, event)
            option = app.SelectAxisDropDown.Value;
            % Set the axis 
            switch(option)
                case 'X'
                    axis = [1 0 0];
                case 'Y'
                    axis = [0 1 0];
                case 'Z'
                    axis = [0 0 1];
            end
            
            x = app.v1Spinner.Value;
            y = app.v2Spinner.Value;
            z = app.v3Spinner.Value;

            vector = [x,y,z];
            angle = norm(vector);

            for i = 3
                axis(i) = vector(i) / angle; 
            end
             rvm = RotationMatrixPrincipalAxis(app,axis,angle);
  
             DrawCube(app,rvm);
            
        end

        % Callback function: PrincipalAngleSpinner, PrincipalAxisButtonGroup
        function PrincipalAxisButtonGroupSelectionChanged(app, event)
            principalAxis = app.PrincipalAxisButtonGroup.SelectedObject.Text;
            angle = app.PrincipalAngleSpinner.Value;

   
            switch(principalAxis)
                case 'X'
                    axis = [1 0 0];
                case 'Y'
                    axis = [0 1 0];
                case 'Z'
                    axis = [0 0 1];
            end

            rvm = RotationMatrixPrincipalAxis(app,axis,angle);
            
            DrawCube(app,rvm);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Cube')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.QuaternionLabel = uilabel(app.GridLayout);
            app.QuaternionLabel.Text = 'Quaternion';
            app.EulerprincipalAngleandAxisLabel = uilabel(app.GridLayout);
            app.EulerprincipalAngleandAxisLabel.Text = 'Euler principal Angle and Axis';
            app.EulerAnglesLabel = uilabel(app.GridLayout);
            app.EulerAnglesLabel.Text = 'Euler Angles';
            app.RotationVectorLabel = uilabel(app.GridLayout);
            app.RotationVectorLabel.Text = 'Rotation Vector';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PrincipalAxisButtonGroup = uibuttongroup(app.GridLayout);
            app.PrincipalAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PrincipalAxisButtonGroupSelectionChanged, true);
            app.PrincipalAxisButtonGroup.Title = 'Principal Axis';
            app.XButton = uiradiobutton(app.PrincipalAxisButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.PrincipalAxisButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.PrincipalAxisButtonGroup);
            app.ZButton.Text = 'Z';
            app.RotationMatrixLabel = uilabel(app.GridLayout);
            app.RotationMatrixLabel.Text = 'Rotation Matrix';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'x'; 'y'; 'z'};
            app.UITable.RowName = {};
            app.RollSpinnerLabel = uilabel(app.GridLayout);
            app.RollSpinnerLabel.Text = 'Roll';
            app.RollSpinner = uispinner(app.GridLayout);
            app.RollSpinner.Limits = [0 360];
            app.RollSpinner.ValueChangedFcn = createCallbackFcn(app, @RollSpinnerValueChanged, true);
            app.PitchSpinnerLabel = uilabel(app.GridLayout);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.PitchSpinner = uispinner(app.GridLayout);
            app.PitchSpinner.Limits = [0 360];
            app.PitchSpinner.ValueChangedFcn = createCallbackFcn(app, @RollSpinnerValueChanged, true);
            app.YawSpinnerLabel = uilabel(app.GridLayout);
            app.YawSpinnerLabel.Text = 'Yaw';
            app.YawSpinner = uispinner(app.GridLayout);
            app.YawSpinner.Limits = [0 360];
            app.YawSpinner.ValueChangedFcn = createCallbackFcn(app, @RollSpinnerValueChanged, true);
            app.wSpinnerLabel = uilabel(app.GridLayout);
            app.wSpinnerLabel.Text = 'w';
            app.wSpinner = uispinner(app.GridLayout);
            app.wSpinner.Step = 0.1;
            app.wSpinner.Limits = [-1 1];
            app.wSpinner.ValueChangedFcn = createCallbackFcn(app, @wSpinnerValueChanged, true);
            app.xSpinnerLabel = uilabel(app.GridLayout);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.GridLayout);
            app.xSpinner.Step = 0.1;
            app.xSpinner.Limits = [-1 1];
            app.xSpinner.ValueChangedFcn = createCallbackFcn(app, @wSpinnerValueChanged, true);
            app.ySpinnerLabel = uilabel(app.GridLayout);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.GridLayout);
            app.ySpinner.Step = 0.1;
            app.ySpinner.Limits = [-1 1];
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @wSpinnerValueChanged, true);
            app.zSpinnerLabel = uilabel(app.GridLayout);
            app.zSpinnerLabel.Text = 'z';
            app.zSpinner = uispinner(app.GridLayout);
            app.zSpinner.Step = 0.1;
            app.zSpinner.Limits = [-1 1];
            app.SelectAxisDropDownLabel = uilabel(app.GridLayout);
            app.SelectAxisDropDownLabel.Text = 'Select Axis';
            app.SelectAxisDropDown = uidropdown(app.GridLayout);
            app.SelectAxisDropDown.Items = {'X', 'Y', 'Z'};
            app.SelectAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAxisDropDownValueChanged, true);
            app.SelectAxisDropDown.Value = 'X';
            app.v1SpinnerLabel = uilabel(app.GridLayout);
            app.v1SpinnerLabel.Text = 'v1';
            app.v1Spinner = uispinner(app.GridLayout);
            app.v1Spinner.ValueChangedFcn = createCallbackFcn(app, @SelectAxisDropDownValueChanged, true);
            app.v2SpinnerLabel = uilabel(app.GridLayout);
            app.v2SpinnerLabel.Text = 'v2';
            app.v2Spinner = uispinner(app.GridLayout);
            app.v2Spinner.ValueChangedFcn = createCallbackFcn(app, @SelectAxisDropDownValueChanged, true);
            app.v3SpinnerLabel = uilabel(app.GridLayout);
            app.v3SpinnerLabel.Text = 'v3';
            app.v3Spinner = uispinner(app.GridLayout);
            app.v3Spinner.ValueChangedFcn = createCallbackFcn(app, @SelectAxisDropDownValueChanged, true);
            app.PrincipalAngleSpinnerLabel = uilabel(app.GridLayout);
            app.PrincipalAngleSpinnerLabel.Text = 'Principal Angle';
            app.PrincipalAngleSpinner = uispinner(app.GridLayout);
            app.PrincipalAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @PrincipalAxisButtonGroupSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CubeRotation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end